classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        HTML            matlab.ui.control.HTML
        Lamp            matlab.ui.control.Lamp
        Label_4         matlab.ui.control.Label
        loadButton      matlab.ui.control.Button
        PlayButton      matlab.ui.control.Button
        DropDown_2      matlab.ui.control.DropDown
        Label_3         matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        DropDown        matlab.ui.control.DropDown
        Label           matlab.ui.control.Label
        YuYStudioLabel  matlab.ui.control.Label
        RightPanel      matlab.ui.container.Panel
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        t % xlsx 表格对象
        newData;
        mouse_ind;
        music_src;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.t = readcell("音乐合成大作业/assets/sound.xlsx");
            app.UITable.Data = app.t;
      
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.t = app.UITable.Data;
            nr = {[],[]};
            app.UITable.Data = [app.t;nr];
            app.t = app.UITable.Data;
            newData1 = app.t;
            set(app.UITable,'Data',newData1);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.UITable.Data(app.mouse_ind,:) = [];
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if length(indices)>=1
            app.mouse_ind = indices(1);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            tempTTxlsx = './matlab/音乐合成大作业/assets/sound.xlsx';
            writecell(app.UITable.Data,tempTTxlsx);
            % xlswrite(tempTTxlsx,app.UITable.Data); %数据写入文件
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Fs = 8000;
            beat_len = 1;
            song_cell = app.UITable.Data;
            len = size(song_cell);
            len = len(1);
            song = zeros(len,2);
            for i = 1:len
                for j= 1:2
                a = cell2mat(song_cell(i,j));
                song(i,j) = str2double(a);
                end
            end
            
            res = [];
            harmonics = 1:10;
            tunes = my_get_tunes(app.DropDown_2.Value);
            switch (app.DropDown.Value)
                case "纯纯正弦"
                    amps = [1,zeros(1,9)]';
                case "电音钢琴"
                    amps = [1,0.340,0.102,0.085,0.070,0.065,0.028,0.085,0.011,0.030]';
                case "电音吉他"
                    amps = [0.0544,0.0793,0.0522,0.0599,0,0,0.0195,0,0,0]';
                otherwise
                    amps = importdata(app.music_src);
                    si = size(amps);
                    if (si(1)~=1 && si(2)~= 10 )
                        error("载入音源文件矩阵size应为[1,10]!");
                    end
                   
            end

            for i = 1 : len
                f = tunes(song(i, 1)); %对应唱名的频率
                time_len = song(i, 2) * beat_len;
                ti = linspace(0, time_len - 1 / Fs, Fs * time_len)'; % 采样
                waves = sin(2*pi*f.*(ti*harmonics));
                tmp_res = waves*amps.*envelop_piano(ti);
                res = [res; tmp_res];
            end
            plot(app.UIAxes,(0 : length(res) - 1) / Fs, res);
            app.Lamp.Color = 'g';
            sound(res,Fs);

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "其他自选"
                app.EditField.Enable = "on";
                app.loadButton.Enable = "on";
            else
                app.EditField.Enable = "off";
                app.loadButton.Enable = "off";
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [file,path,~] = uigetfile;
            if(file ~= 0)
            app.EditField.Value = file;
            app.music_src = [path,file];
           
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {551, 551};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.YuYStudioLabel = uilabel(app.LeftPanel);
            app.YuYStudioLabel.Text = 'Yu.Y  Studio';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '乐器';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'纯纯正弦', '电音钢琴', '电音吉他', '其他自选'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '纯纯正弦';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '音源路径';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '自然大调';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'C', 'D', 'E', 'F', 'G', 'A', 'B'};
            app.DropDown_2.Value = 'C';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.loadButton = uibutton(app.LeftPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Enable = 'off';
            app.loadButton.Text = 'load';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.HTML = uihtml(app.LeftPanel);
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><body>    <h3>Help</h3>    <!-- 无序列表 -->    <ul>      <li>选择您的乐器和自然大调！↑</li>      <li>编写您的乐曲！↗</li>      <li>低音区1-7<br/>中音区8-14<br/>高音区15-21<br/>休止          22</li>      <li>享受您的音乐！↓</li>    </ul></body></html>';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 't(s)')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'唱名'; '时长(s)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '添加行';
            app.Button_3 = uibutton(app.RightPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '删除行';
            app.Button_4 = uibutton(app.RightPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '保存';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end